classdef ProGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeSliderLabel                matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        RecordButton                   matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        NoiseCancellationLabel         matlab.ui.control.Label
        PlayNoisecancelledSoundButton  matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        PlayNoiseAddedSoundButton      matlab.ui.control.Button
        PlayOriginalSoundButton        matlab.ui.control.Button
        AddNoisePanel                  matlab.ui.container.Panel
        GaussianButton                 matlab.ui.control.Button
        Button2                        matlab.ui.control.Button
        Button3                        matlab.ui.control.Button
        RemoveNoisePanel               matlab.ui.container.Panel
        LMSButton                      matlab.ui.control.Button
        NLMSButton                     matlab.ui.control.Button
        RLSButton                      matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        dd; % Description
        NSd;
        NFd;
        mm;
        NAd;
    end
    
    
    methods (Access = public)
        
        function [Sounddata] = soundRec(app,time)
        %UNTITLED Summary of this function goes here
        %   Detailed explanation goes here
        Fs = 8000;
        %[d,Fs] = audioread('music.wav'); % Loading of input signal
        recording = audiorecorder;
        disp('Recording....');
        recordblocking(recording,time); %Recording sound for 10 seconds 
        Sounddata =  getaudiodata(recording);
        end
        function [LMScancelSound]=soundLMS(app,m,NoiseSound)
        
        reference_signal = wgn(m,1,8); 
        FIR_fil = fir1(12, 0.6);              % Designing a FIR filter for adjusting weigts
        u = filter(FIR_fil, 1, reference_signal);   
        order = 12;       % order of the filter(maximum no.of delay elements)
        mu = 0.0003642;     % It is constant(optimal value of mu value is calculated using experiments)
        n = length(NoiseSound);     %length of noised_signal signal
        LMScancelSound = zeros(order,1);      %initializing vectors
        E = zeros(1,m);
        for k = 12:n
         U = u(k-11:k);
         y = U'*LMScancelSound;                % preliminary output signal 
         E(k) = NoiseSound(k)-y;     % error
         LMScancelSound = LMScancelSound + mu*E(k)*U;  
        end 
        end
        function [NoiseSoundD,m]=soundNoise(app,d)

            d = d / rms(d, 1);                % Normalization of the signal
            m = length(d);                   % length of the signal
            %% Creating Noise(White Gaussian Noise)
            reference_signal = wgn(m,1,8); % white gaussian noise with the length of the input signal
            %% Designing digital filter
            FIR_fil = fir1(12, 0.6);              % Designing a FIR filter for adjusting weigts
            u = filter(FIR_fil, 1, reference_signal);        % Filtering the reference signal
            %% Addition of noise to the input signal
            NoiseSoundD = d + u;    % signal to be filtered

end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.Lamp.Color=[0,1,0];
            app.dd=app.soundRec(app.TimeSlider.Value);
            app.Lamp.Color=[0,0,0];
            app.EditField.Value="Inside Record callback";
            
        end

        % Button pushed function: PlayOriginalSoundButton
        function PlayOriginalSoundButtonPushed(app, event)
            app.EditField.Value="Inside play original callback";
            
            sound(app.dd);
        end

        % Button pushed function: PlayNoiseAddedSoundButton
        function PlayNoiseAddedSoundButtonPushed(app, event)
            Fs=8000;
            app.EditField.Value="Inside Noise added callback";
            
            soundsc(app.NAd,Fs);
        end

        % Button pushed function: PlayNoisecancelledSoundButton
        function PlayNoisecancelledSoundButtonPushed(app, event)
            Fs=8000;
            app.EditField.Value="Inside noise cancelled callback";
            
            soundsc(app.NFd,Fs);
        end

        % Button pushed function: LMSButton
        function LMSButtonPushed(app, event)
            app.EditField.Value="Inside LMS";
                app.NFd=app.soundLMS(app.mm,app.NSd);
        end

        % Button pushed function: NLMSButton
        function NLMSButtonPushed(app, event)
            %app.NFd=app.soundLMS(app.mm,app.NSd);
                app.EditField.Value="selected NLMS";
                
        end

        % Button pushed function: RLSButton
        function RLSButtonPushed(app, event)
            %app.NFd=app.soundLMS(app.mm,app.NSd);
                app.EditField.Value="selected RLS";
                
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            
            app.EditField.Value="Inside Gaussian";
            
            app.NAd,app.mm=app.soundNoise(app.dd);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time ';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0 30];
            app.TimeSlider.Value = 5;
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = {'Record'; ''};
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NoiseCancellationLabel = uilabel(app.UIFigure);
            app.NoiseCancellationLabel.Text = 'Noise Cancellation';
            app.PlayNoisecancelledSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayNoisecancelledSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoisecancelledSoundButtonPushed, true);
            app.PlayNoisecancelledSoundButton.Text = 'Play Noise cancelled Sound';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.PlayNoiseAddedSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayNoiseAddedSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoiseAddedSoundButtonPushed, true);
            app.PlayNoiseAddedSoundButton.Text = 'Play Noise Added Sound';
            app.PlayOriginalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSoundButtonPushed, true);
            app.PlayOriginalSoundButton.Text = 'Play Original Sound';
            app.AddNoisePanel = uipanel(app.UIFigure);
            app.AddNoisePanel.Title = 'Add Noise';
            app.GaussianButton = uibutton(app.AddNoisePanel, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.Button2 = uibutton(app.AddNoisePanel, 'push');
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.AddNoisePanel, 'push');
            app.Button3.Text = 'Button3';
            app.RemoveNoisePanel = uipanel(app.UIFigure);
            app.RemoveNoisePanel.Title = 'Remove Noise';
            app.LMSButton = uibutton(app.RemoveNoisePanel, 'push');
            app.LMSButton.ButtonPushedFcn = createCallbackFcn(app, @LMSButtonPushed, true);
            app.LMSButton.Text = 'LMS';
            app.NLMSButton = uibutton(app.RemoveNoisePanel, 'push');
            app.NLMSButton.ButtonPushedFcn = createCallbackFcn(app, @NLMSButtonPushed, true);
            app.NLMSButton.Text = 'NLMS';
            app.RLSButton = uibutton(app.RemoveNoisePanel, 'push');
            app.RLSButton.ButtonPushedFcn = createCallbackFcn(app, @RLSButtonPushed, true);
            app.RLSButton.Text = 'RLS';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end